classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ItsEditField       matlab.ui.control.EditField
        ItsEditFieldLabel  matlab.ui.control.Label
        ClassifyButton     matlab.ui.control.Button
        PickImageButton    matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PickImageButton
        function PickImageButtonPushed(app, event)
            global a;
            [filename, pathname] = uigetfile('*', 'Pick an Image');
            filename = strcat(pathname, filename);
            a = imread(filename);
            imshow(a, 'Parent', app.UIAxes);
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            global a;
            outputFolder = fullfile('animals');
            rootFolder = fullfile(outputFolder, 'animals');

            categories = {
                'wolf', 'whale', 'turkey', 'pig', 'antelope', 'badger', 'bat', 'bear', 'bee', 'beetle', ...
                'bison', 'boar', 'butterfly', 'cat', 'caterpillar', 'chimpanzee', 'cockroach', 'cow', 'coyote', 'crab', ...
                'crow', 'deer', 'dog', 'dolphin', 'donkey', 'dragonfly', 'duck', 'eagle', 'elephant', 'flamingo', ...
                'fly', 'fox', 'goat', 'goldfish', 'goose', 'gorilla', 'grasshopper', 'hamster', 'hare', 'hedgehog', ...
                'hippopotamus', 'hornbill', 'horse', 'hummingbird', 'hyena', 'jellyfish', 'kangaroo', 'koala', 'ladybugs', 'leopard', ...
                'lion', 'lizard', 'lobster', 'mosquito', 'moth', 'mouse', 'octopus', 'okapi', 'orangutan', 'otter', ...
                'owl', 'ox', 'oyster', 'panda', 'parrot', 'pelecaniformes', 'penguin', 'pig', 'pigeon', 'porcupine', ...
                'possum', 'raccoon', 'rat', 'reindeer', 'rhinoceros', 'sandpiper', 'seahorse', 'seal', 'shark', 'sheep', ...
                'snake', 'sparrow', 'squid', 'squirrel', 'starfish', 'swan', 'tiger', 'turkey', 'turtle', 'whale', ...
                'wolf', 'wombat', 'woodpecker', 'zebra'
                };


            imds = imageDatastore(fullfile(rootFolder, categories), "LabelSource", "foldernames");

            tbl = countEachLabel(imds);
            minSetCount = min(tbl{:, 2});

            imds = splitEachLabel(imds, minSetCount, 'randomized');

            % wolf = find(imds.Labels == 'wolf', 1);
            % whale = find(imds.Labels == 'whale', 1);
            % turkey = find(imds.Labels == 'turkey', 1);
            % pig = find(imds.Labels == 'pig', 1);

            % figure;
            % subplot(2, 2, 1);
            % imshow(readimage(imds, wolf));
            % subplot(2, 2, 2);
            % imshow(readimage(imds, whale));
            % subplot(2, 2, 3);
            % imshow(readimage(imds, turkey));
            % subplot(2, 2, 4);
            % imshow(readimage(imds, pig));

            net = resnet50();
            % figure;
            % plot(net);
            % title('Architecture of ResNet-50');
            % set(gca, 'YLim', [150 170]);

            net.Layers(1);
            net.Layers(end);

            numel(net.Layers(end).ClassNames);
            [trainingSet, testSet] = splitEachLabel(imds, 0.3, 'randomized');

            imageSize = net.Layers(1).InputSize;

            augmentedTrainingSet = augmentedImageDatastore(imageSize, trainingSet, ...
                'ColorPreprocessing', 'gray2rgb');

            augmentedTestSet = augmentedImageDatastore(imageSize, testSet, ...
                'ColorPreprocessing', 'gray2rgb');

            w1 = net.Layers(2).Weights;
            w1 = mat2gray(w1);

            % figure;
            % montage(w1);
            % title('First Convolutional Layer Weight');

            featureLayer = 'fc1000';
            trainingFeatures = activations(net, ...
                augmentedTrainingSet, featureLayer, 'MiniBatchSize', 32, 'OutputAs', 'columns');

            trainingLabels = trainingSet.Labels;
            classifier = fitcecoc(trainingFeatures, trainingLabels, 'Learners','linear', ...
                'Coding','onevsall', 'ObservationsIn', 'columns');

            testFeatures = activations(net, ...
                augmentedTestSet, featureLayer, 'MiniBatchSize', 32, 'OutputAs', 'columns');

            predictLabels = predict(classifier, testFeatures, 'ObservationsIn', 'columns');

            testLabels = testSet.Labels;
            confMat = confusionmat(testLabels, predictLabels);
            confMat = bsxfun(@rdivide, confMat, sum(confMat, 2));

            mean(diag(confMat));

            newImage = a;

            ds = augmentedImageDatastore(imageSize, newImage, ...
                'ColorPreprocessing', 'gray2rgb');

            imageFeatures = activations(net, ...
                ds, featureLayer, 'MiniBatchSize', 32, 'OutputAs', 'columns');

            label = predict(classifier, imageFeatures, 'ObservationsIn', 'columns');
            label = char(label);
            label = string(label);
            app.ItsEditField.Value = label;
        end

        % Value changed function: ItsEditField
        function ItsEditFieldValueChanged(app, event)
            value = app.ItsEditField.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.PickImageButton = uibutton(app.UIFigure, 'push');
            app.PickImageButton.ButtonPushedFcn = createCallbackFcn(app, @PickImageButtonPushed, true);
            app.PickImageButton.Text = 'Pick Image';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.ItsEditFieldLabel = uilabel(app.UIFigure);
            app.ItsEditFieldLabel.Text = 'It''s';
            app.ItsEditField = uieditfield(app.UIFigure, 'text');
            app.ItsEditField.ValueChangedFcn = createCallbackFcn(app, @ItsEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end